classdef Kidney_Stone_Detection_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ProcessImageButton       matlab.ui.control.Button
        ResultLabel              matlab.ui.control.Label
        FinalCTScanLabel         matlab.ui.control.Label
        Image_Final              matlab.ui.control.Image
        OriginalCTScanLabel      matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        FileNameLabel            matlab.ui.control.Label
        FileNamesDropDown        matlab.ui.control.DropDown
        FileNamesLabel           matlab.ui.control.Label
        LoadOriginalImageButton  matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function results = Image_process(app,filename)
            % Read the desired image
            I = imread(filename);
            I_original = I;

            % Convert the image to grayscale
            I = rgb2gray(I);

            % Create a binary image of only the grey and white pixels
            binaryI = I>40;

            % Erode the image to eliminate artifacts
            seD = strel('sphere',3);
            binaryI = imerode(binaryI,seD);

            % Dilate the image to bring back kidney size
            binaryI = imdilate(binaryI,seD);

            %fill in holes
            binaryI = imfill(binaryI, 'holes');

            % Get region properties
            stats = regionprops(binaryI, 'Area');

            % Get the maximum area from the region
            cell = struct2cell(stats);
            M = cell2mat(cell);
            maxvalue = max(M);

            % Declare the area range you want to keep and not filter
            if maxvalue >= 20000 && maxvalue < 25000
                minArea = 2800;
                maxArea = 3600;
            elseif maxvalue >= 25000 && maxvalue < 50000
                minArea = 3000;
                maxArea = 5000;
            elseif maxvalue >= 50000
                minArea = 2500;
                maxArea = 4000;
            else
                minArea = 2000;
                maxArea = 3500;
            end

            % Add label to each connected object
            labels = bwlabel(binaryI);

            % Filter based on area range checks for each one that they fit inside the
            % area
            binaryI = ismember(labels, find([stats.Area] >= minArea & [stats.Area] <= maxArea));

            % Dilate the kidneys to ensure kidney stone capture
            binaryI = imdilate(binaryI,seD);

            % Binarize original image to isolate anything bright white
            whiteI = I > 250;

            % Compare if kidney stones are in the same location as kindeys
            Final = whiteI.*binaryI;

            %if any values in overlay then prints kidney stone present
            if any(Final(:))
                % Overlay the mask of kidney stones onto the original image
                finalOverlay = labeloverlay(I, Final);
                app.Image_Final.ImageSource = finalOverlay;
                results = 'Kidney Stone(s) detected';
                %if no values in overlay prints kidney stone not present
            else
                app.Image_Final.ImageSource = I_original;
                results = 'Kidney Stone(s) not detected';
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadOriginalImageButton
        function LoadOriginalImageButtonPushed(app, event)
            filename = app.FileNamesDropDown.Value;
            % Display the file name in the command window (you can modify this part)
            app.FileNameLabel.Text = ['File name selected: ' filename];
            img = imread(filename);
            app.Image.ImageSource = img;
        end

        % Button pushed function: ProcessImageButton
        function ProcessImageButtonPushed(app, event)
            results = Image_process(app,app.FileNamesDropDown.Value);
            app.ResultLabel.Text = ['Result: ' results];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadOriginalImageButton = uibutton(app.UIFigure, 'push');
            app.LoadOriginalImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOriginalImageButtonPushed, true);
            app.LoadOriginalImageButton.Text = 'Load Original Image';
            app.FileNamesLabel = uilabel(app.UIFigure);
            app.FileNamesLabel.Text = 'File Names:';
            app.FileNamesDropDown = uidropdown(app.UIFigure);
            app.FileNamesDropDown.Items = {'Stone- (2).jpeg', 'Normal- (4266).jpeg', 'Normal- (4252).jpeg', 'Stone- (436).jpeg', 'Stone- (389).jpeg', 'Stone- (10).jpeg', 'Stone- (424).jpeg', 'Normal- (31).jpeg', 'Normal- (4214).jpeg', 'Normal- (4267).jpeg'};
            app.FileNamesDropDown.Value = 'Stone- (2).jpeg';
            app.FileNameLabel = uilabel(app.UIFigure);
            app.FileNameLabel.Text = 'File Name: ';
            app.Image = uiimage(app.UIFigure);
            app.OriginalCTScanLabel = uilabel(app.UIFigure);
            app.OriginalCTScanLabel.Text = 'Original CT Scan';
            app.Image_Final = uiimage(app.UIFigure);
            app.FinalCTScanLabel = uilabel(app.UIFigure);
            app.FinalCTScanLabel.Text = 'Final CT Scan';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result:';
            app.ProcessImageButton = uibutton(app.UIFigure, 'push');
            app.ProcessImageButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessImageButtonPushed, true);
            app.ProcessImageButton.Text = 'Process Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Kidney_Stone_Detection_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end